classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlaySoundButton                 matlab.ui.control.Button
        PlaymodifiedsoundButton         matlab.ui.control.Button
        T60SliderLabel                  matlab.ui.control.Label
        T60Slider                       matlab.ui.control.Slider
        ArtificalReverbationLabel       matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FeedbackmatrixtypeDropDownLabel  matlab.ui.control.Label
        FeedbackmatrixtypeDropDown      matlab.ui.control.DropDown
        NumberofdelaylinesSpinnerLabel  matlab.ui.control.Label
        NumberofdelaylinesSpinner       matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
       track_name;
       T60 = 2;         % Reverberation time -- higher value brings more echo ]1, 4[, for best results
       N = 16;
       A = eye(16)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            [wav, fs] = audioread(app.track_name);
            soundsc(wav, fs);
            histogram(app.UIAxes, wav)
        end

        % Button pushed function: PlaymodifiedsoundButton
        function PlaymodifiedsoundButtonPushed(app, event)
            [wav, fs] = audioread(app.track_name);
            unitimpulse = zeros(1e5,1);
            unitimpulse(1) = 1;
                                                                                       
            g_dB = -60/(app.T60 * fs);
            g_lin = power(10, (g_dB/20));
            
            b_gains = rand(app.N,1);                % Randomised values between [0, 1] for input gains b_gains
            c_gains = rand(1, app.N);               % Randomised values between [0, 1] for output gains c_gains
            m_delays = randi([2000,10000],[1,app.N]); % For randomised delay values between [500, 2000]
            g_gains = power(g_lin, m_delays);
            
            y = FDN_func(wav, app.A, b_gains, c_gains, g_gains, m_delays);
            
            soundsc(y, fs)
            histogram(app.UIAxes, wav)
            histogram(app.UIAxes_2,y)
            
            impulse_response = FDN_func(unitimpulse, app.A, b_gains, c_gains, g_gains, m_delays);            
            L = length(impulse_response);
            stem(app.UIAxes_3, linspace(1, L, L), 10*log10(abs(impulse_response)), '|', 'BaseValue', -Inf)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename, path] = uigetfile();
            app.FilenameEditField.Value = filename;
            app.track_name = filename;  
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Value changed function: T60Slider
        function T60SliderValueChanged(app, event)
            value = app.T60Slider.Value;
            app.T60 = value;
        end

        % Callback function
        function NSliderValueChanged(app, event)
            value = app.NSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            app.N = event.Source.MajorTicks(minIdx);
            FeedbackmatrixtypeDropDownValueChanged(app,app.FeedbackmatrixtypeDropDown.Value)
        end

        % Value changed function: FeedbackmatrixtypeDropDown
        function FeedbackmatrixtypeDropDownValueChanged(app, event)
            value = app.FeedbackmatrixtypeDropDown.Value;
            if strcmp(value,'Identity Matrix')
                app.A = eye(app.N);
            elseif strcmp(value,'Magic Matrix')
                app.A = magic(app.N);
            elseif strcmp(value,'Hadamard Matrix')
                app.A = hadamard(app.N);
            else
                app.A = randn(app.N);
            end
        end

        % Value changed function: NumberofdelaylinesSpinner
        function NumberofdelaylinesSpinnerValueChanged(app, event)
            value = app.NumberofdelaylinesSpinner.Value;
            app.N = value;
            FeedbackmatrixtypeDropDownValueChanged(app, app.FeedbackmatrixtypeDropDown.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.PlaymodifiedsoundButton = uibutton(app.UIFigure, 'push');
            app.PlaymodifiedsoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaymodifiedsoundButtonPushed, true);
            app.PlaymodifiedsoundButton.Text = 'Play modified sound';
            app.T60SliderLabel = uilabel(app.UIFigure);
            app.T60SliderLabel.Text = 'T60';
            app.T60Slider = uislider(app.UIFigure);
            app.T60Slider.Limits = [0 2];
            app.T60Slider.MajorTicks = [0 0.5 1 1.5 2];
            app.T60Slider.ValueChangedFcn = createCallbackFcn(app, @T60SliderValueChanged, true);
            app.T60Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
            app.T60Slider.Value = 1;
            app.ArtificalReverbationLabel = uilabel(app.UIFigure);
            app.ArtificalReverbationLabel.Text = 'Artifical Reverbation';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FeedbackmatrixtypeDropDownLabel = uilabel(app.UIFigure);
            app.FeedbackmatrixtypeDropDownLabel.Text = 'Feedback matrix type';
            app.FeedbackmatrixtypeDropDown = uidropdown(app.UIFigure);
            app.FeedbackmatrixtypeDropDown.Items = {'Identity matrix', 'Magic Matrix', 'Hadamard Matrix', 'Random Matrix'};
            app.FeedbackmatrixtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FeedbackmatrixtypeDropDownValueChanged, true);
            app.FeedbackmatrixtypeDropDown.Value = 'Identity matrix';
            app.NumberofdelaylinesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofdelaylinesSpinnerLabel.Text = 'Number of delay lines';
            app.NumberofdelaylinesSpinner = uispinner(app.UIFigure);
            app.NumberofdelaylinesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofdelaylinesSpinnerValueChanged, true);
            app.NumberofdelaylinesSpinner.Value = 16;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original sound histogram')
            xlabel(app.UIAxes, 'Value')
            ylabel(app.UIAxes, 'Quantity')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Modified sound histogram')
            xlabel(app.UIAxes_2, 'Value')
            ylabel(app.UIAxes_2, 'Quantity')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Impulse response')
            xlabel(app.UIAxes_3, 'Samples')
            ylabel(app.UIAxes_3, 'Amplitude [dB]')
            app.UIAxes_3.XLim = [-10000 100000];
            app.UIAxes_3.YLim = [-Inf 10];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end